classdef finalproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        House1EditFieldLabel  matlab.ui.control.Label
        House1EditField       matlab.ui.control.EditField
        House2EditFieldLabel  matlab.ui.control.Label
        House2EditField       matlab.ui.control.EditField
        EnterAddressinFollowingFormat66LindyDriveCarmelNYLabel  matlab.ui.control.Label
        ZestimateButton       matlab.ui.control.Button
        BasicInfoButton       matlab.ui.control.Button
        ValuationButton       matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        Label_zestimate       matlab.ui.control.Label
        NumberofbedsbathsandsquarefootageLabel  matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: BasicInfoButton
        function basicinfo(app, event)
            %calls functions that return basic info and plots
            house1 = app.House1EditField.Value;
            house2 = app.House2EditField.Value;
            try
            [bath1,bed1,sqfoot1] = getdeepsearch(house1);
            [bath2,bed2,sqfoot2] = getdeepsearch(house2);
            y = [bed1 bed2;bath1 bath2;sqfoot1/1000 sqfoot2/1000];
            b = bar(app.UIAxes,y);
            b(1).FaceColor = 'b';
            b(2).FaceColor = 'y';
            title(app.UIAxes,'Bed Bath and Square Footage');
            ylabel(app.UIAxes,'number of bed/bath/sqft');
            xlabel(app.UIAxes,' ');
            xticklabels(app.UIAxes,{'Beds','Baths','Square Footage(*10^3)'});
            catch
                app.House1EditField.Value = 'Address not listed on Zillow :(';
            end
            try
               [bath2, bed2,sqfoot2] = getdeepsearch(house2); 
            catch
              app.House2EditField.Value = 'Address not listed on Zillow :(';
            end
         
        end

        % Callback function
        function estimateprice(app, event)
            
        end

        % Button pushed function: ValuationButton
        function valuation(app, event)
           %calls functions that get valuations and change and plots
           try
           house1 = app.House1EditField.Value;
            house2 = app.House2EditField.Value;
            [low1,high1,thirty_change1] = getvaluation(house1);
            [low2,high2,thirty_change2] = getvaluation(house2);
            y = [low1 low2;high1 high2;thirty_change1 thirty_change2];
            b = bar(app.UIAxes,y);
            ylabel(app.UIAxes,'dollars($)');
            xlabel(app.UIAxes,' ');
            b(1).FaceColor = 'b';
            b(2).FaceColor = 'y';
            title(app.UIAxes,'Valuation and 30-Day Change');
            xticklabels(app.UIAxes,{'Low Valuation','High Valuation','30-Day Change'});
           catch
             app.House1EditField.Value = 'Address not listed on Zillow :(';
           end
           try
             [low2,high2,thirty_change2] = getvaluation(house2); 
            catch
              app.House2EditField.Value = 'Address not listed on Zillow :(';
            end  
        end

        % Button pushed function: ZestimateButton
        function zestimate(app, event)
            %calls functions to get zestimate and plots. 
            house1 = app.House1EditField.Value;
            house2 = app.House2EditField.Value;
            
           try
            [zestimate1,homeindex1] = getzestimate(house1); 
            [zestimate2,homeindex2] = getzestimate(house2); 
            y = [zestimate1 zestimate2; homeindex1 homeindex2];      
            b = bar(app.UIAxes,y);
            b(1).FaceColor = 'b';
            b(2).FaceColor = 'y';
            ylabel(app.UIAxes,'dollars($)');
            xlabel(app.UIAxes,' ');
            title(app.UIAxes,'Zestimate and Median Home Index');
            app.Label_zestimate.FontColor = [0 0 0];
            xticklabels(app.UIAxes,{'Zestimate','Median Home Index'});
            fid = fopen('houseZests.txt','a');
            fprintf(fid,'$%s',zestimate1);
            fprintf(fid,' $%s\n',zestimate2);
            fclose(fid);
            catch
              app.House1EditField.Value = 'Address not listed on Zillow :(';
            end
             
            try
            [zestimate2,homeindex2] = getzestimate(house2); 
            catch
              app.House2EditField.Value = 'Address not listed on Zillow :(';
            end
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.House1EditFieldLabel = uilabel(app.UIFigure);
            app.House1EditFieldLabel.Text = 'House 1';
            app.House1EditField = uieditfield(app.UIFigure, 'text');
            app.House1EditField.Value = 'Address';
            app.House2EditFieldLabel = uilabel(app.UIFigure);
            app.House2EditFieldLabel.Text = 'House 2';
            app.House2EditField = uieditfield(app.UIFigure, 'text');
            app.House2EditField.Value = 'Address';
            app.EnterAddressinFollowingFormat66LindyDriveCarmelNYLabel = uilabel(app.UIFigure);
            app.EnterAddressinFollowingFormat66LindyDriveCarmelNYLabel.Text = 'Enter Address in Following Format: 66 Lindy Drive,Carmel NY';
            app.ZestimateButton = uibutton(app.UIFigure, 'push');
            app.ZestimateButton.ButtonPushedFcn = createCallbackFcn(app, @zestimate, true);
            app.ZestimateButton.Text = 'Zestimate ';
            app.BasicInfoButton = uibutton(app.UIFigure, 'push');
            app.BasicInfoButton.ButtonPushedFcn = createCallbackFcn(app, @basicinfo, true);
            app.BasicInfoButton.Text = 'Basic Info';
            app.ValuationButton = uibutton(app.UIFigure, 'push');
            app.ValuationButton.ButtonPushedFcn = createCallbackFcn(app, @valuation, true);
            app.ValuationButton.Text = 'Valuation';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '   ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '     ';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Compare Houses!')
            xlabel(app.UIAxes, 'Characteristic')
            ylabel(app.UIAxes, 'Number')
            app.Label_zestimate = uilabel(app.UIFigure);
            app.Label_zestimate.Text = 'Estimated price of houses as well as median home index (average house price in that area)';
            app.NumberofbedsbathsandsquarefootageLabel = uilabel(app.UIFigure);
            app.NumberofbedsbathsandsquarefootageLabel.Text = 'Number of beds, baths, and square footage.';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Lowest and Highest values in house history, as well as change in price in last 30 days';
        end
    end

    methods (Access = public)

        % Construct app
        function app = finalproject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end